classdef KyvGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        ControllerLabel              matlab.ui.control.Label
        RegulatorSwitch              matlab.ui.control.Switch
        ErrorKnobLabel               matlab.ui.control.Label
        ErrorKnob                    matlab.ui.control.Knob
        ReferenceKnobLabel           matlab.ui.control.Label
        ReferenceKnob                matlab.ui.control.Knob
        PlayButton                   matlab.ui.control.StateButton
        mSliderLabel                 matlab.ui.control.Label
        mSlider                      matlab.ui.control.Slider
        LSliderLabel                 matlab.ui.control.Label
        LSlider                      matlab.ui.control.Slider
        BSliderLabel                 matlab.ui.control.Label
        BSlider                      matlab.ui.control.Slider
        PushButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        SetButton                    matlab.ui.control.Button
        AngleKnobLabel               matlab.ui.control.Label
        AngleKnob                    matlab.ui.control.Knob
        dAngledtKnobLabel            matlab.ui.control.Label
        dAngledtKnob                 matlab.ui.control.Knob
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        ControllerTypeDropDownLabel  matlab.ui.control.Label
        ControllerTypeDropDown       matlab.ui.control.DropDown
        PEditFieldLabel              matlab.ui.control.Label
        PEditField                   matlab.ui.control.NumericEditField
        IEditFieldLabel              matlab.ui.control.Label
        IEditField                   matlab.ui.control.NumericEditField
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        state = [0, 0];
        Tsim = inf;
        t = 0;
        tPlot = 0;
        dt = 1e-2;
        dtPlot = 1/30;
        output=0;
        input=0;
        m = 1;
        L = 10;
        g = 9.81;
        B = 1;
        sz1 = 0.2;
        sz2 = 1;
        sz3 = 0.2;
        k1 = 1;
        k2 = 1;
        r = 0;
        stopSim=0;
        Angle_queue=zeros(1000,1);
        Speed_queue=zeros(1000,1);
        Reference_queue=zeros(1000,1);
        input_queue=zeros(1000,1);
        plotcounter=0;
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function [u] = linearization_law(app,v)
            u = app.m*app.L^2*(v + sin(app.state(1))*app.g/app.L + app.B/app.m/app.L^2*app.state(2));
        end
        
        function [v] = control_law(app)
            v = -app.k1*app.state(2) + app.k2*(app.r - app.state(1));
        end
        
        function [new_state, output] = pendulum_step(app, input)
            value=app.RegulatorSwitch.Value;
            if value == "Off"
                input=0;
            end
            app.input=input;
            new_state = zeros(2,1);
            new_state(1) = app.state(1) + app.dt*app.state(2);
            new_state(2) = app.state(2) + app.dt*(input/app.m/app.L^2 - app.g/app.L*sin(new_state(1)) - app.B/app.m/app.L^2*app.state(2));
            output = new_state(1)-pi/2;
            store_data(app);
        end
        
        function [] = plot_pendulum(app)
            weightxy = [ app.L*cos(app.output), app.L*sin(app.output)];
            hold(app.UIAxes,'off')
            plot(app.UIAxes, [0 weightxy(1)], [0 weightxy(2)], 'k');
            hold(app.UIAxes,'on')
            circle(app, 0,0, app.sz1);
            circle(app, weightxy(1), weightxy(2), app.sz2);
            circle(app, weightxy(1), weightxy(2), app.sz3);
            axis(app.UIAxes,[(-app.L-app.sz2) (app.L+app.sz2) (-app.L-app.sz2) (app.L+app.sz2)]);
            grid(app.UIAxes,'on')
        end
        function [] = circle(app,x,y,r)
            
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(app.UIAxes, xunit, yunit, 'k');
            
        end
        function []=store_data(app)
            app.Angle_queue=[app.Angle_queue; app.state(1)];
            app.Speed_queue=[app.Speed_queue; app.state(2)];
            app.Reference_queue=[app.Reference_queue; app.r];
            app.input_queue=[app.input_queue; app.input];
            app.Angle_queue(1)=[];
            app.Speed_queue(1)=[];
            app.Reference_queue(1)=[];
            app.input_queue(1)=[];
            
        end
        
        function []=plot_data(app)
            
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,app.Reference_queue,'LineWidth',2,'Color','r')
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,app.Angle_queue,'LineWidth',2,'Color','b')
                plot(app.UIAxes2_2,app.Speed_queue,'LineWidth',2,'Color','b')
                plot(app.UIAxes2_3,app.input_queue,'LineWidth',2,'Color','b')

            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.PlayButton.Text="Pause";
                app.stopSim = 0;
                
                while app.t < app.Tsim
                    [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
                    app.t = app.t + app.dt;
                    app.tPlot = app.tPlot + app.dt;
                    if (app.tPlot > app.dtPlot)
                        plot_pendulum(app);
                        if mod(app.plotcounter,10) == 0
                            plot_data(app);
                        end
                        app.plotcounter=app.plotcounter+1;
                        if app.plotcounter>65000
                            app.plotcounter=0;
                        end
                        app.tPlot = 0;
                        drawnow
                    end
                    if app.stopSim
                        break
                    end
                end
            else
                app.PlayButton.Text="Play";
                app.stopSim = 1;
            end
        end

        % Value changing function: ReferenceKnob
        function ReferenceKnobValueChanging(app, event)
            changingValue = event.Value;
            app.r=changingValue;
        end

        % Button pushed function: PushButton
        function PushButtonPushed(app, event)
            errorVal=app.ErrorKnob.Value;
            app.state(2) = app.state(2) + errorVal;
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            value = app.mSlider.Value;
            app.m=value;
        end

        % Value changed function: LSlider
        function LSliderValueChanged(app, event)
            value = app.LSlider.Value;
            app.L=value;
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.B=value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.state = [0, 0];
            app.output=app.state(1);
            app.m = 1;
            app.L = 10;
            app.B = 1;
            app.r = 0;
            app.ErrorKnob.Value=0;
            app.ReferenceKnob.Value=0;
            app.mSlider.Value=app.m;
            app.LSlider.Value=app.L;
            app.BSlider.Value=app.B;
            
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.state = [app.AngleKnob.Value, app.dAngledtKnob.Value];
            app.output=app.AngleKnob.Value;
            
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            app.ControllerLabel = uilabel(app.UIFigure);
            app.ControllerLabel.Text = 'Controller';
            app.RegulatorSwitch = uiswitch(app.UIFigure, 'slider');
            app.ErrorKnobLabel = uilabel(app.UIFigure);
            app.ErrorKnobLabel.Text = 'Error';
            app.ErrorKnob = uiknob(app.UIFigure, 'continuous');
            app.ErrorKnob.Limits = [-3 3];
            app.ReferenceKnobLabel = uilabel(app.UIFigure);
            app.ReferenceKnobLabel.Text = 'Reference';
            app.ReferenceKnob = uiknob(app.UIFigure, 'continuous');
            app.ReferenceKnob.Limits = [0 6.28318530717959];
            app.ReferenceKnob.ValueChangingFcn = createCallbackFcn(app, @ReferenceKnobValueChanging, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.mSliderLabel = uilabel(app.UIFigure);
            app.mSliderLabel.Text = 'm';
            app.mSlider = uislider(app.UIFigure);
            app.mSlider.Limits = [0.1 5];
            app.mSlider.MajorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.mSlider.MajorTickLabels = {'0.1', '0.5', '1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5'};
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.mSlider.Value = 1;
            app.LSliderLabel = uilabel(app.UIFigure);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.UIFigure);
            app.LSlider.Limits = [1 50];
            app.LSlider.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @LSliderValueChanged, true);
            app.LSlider.Value = 10;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0.05 1.5];
            app.BSlider.MajorTicks = [0.05 0.15 0.3 0.45 0.6 0.75 0.9 1.05 1.2 1.35 1.5];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.Value = 1;
            app.PushButton = uibutton(app.UIFigure, 'push');
            app.PushButton.ButtonPushedFcn = createCallbackFcn(app, @PushButtonPushed, true);
            app.PushButton.Text = 'Push';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.AngleKnobLabel = uilabel(app.UIFigure);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.UIFigure, 'continuous');
            app.AngleKnob.Limits = [0 6.28318530717959];
            app.dAngledtKnobLabel = uilabel(app.UIFigure);
            app.dAngledtKnobLabel.Text = 'dAngle/dt';
            app.dAngledtKnob = uiknob(app.UIFigure, 'continuous');
            app.dAngledtKnob.Limits = [-2 2];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Angle')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'Angle [rad]')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Angular speed')
            xlabel(app.UIAxes2_2, 'time')
            ylabel(app.UIAxes2_2, 'Angular speed [ rad/s]')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Input')
            xlabel(app.UIAxes2_3, 'time')
            ylabel(app.UIAxes2_3, 'u')
            app.ControllerTypeDropDownLabel = uilabel(app.UIFigure);
            app.ControllerTypeDropDownLabel.Text = 'Controller Type';
            app.ControllerTypeDropDown = uidropdown(app.UIFigure);
            app.ControllerTypeDropDown.Items = {'I/O Feedback Lin.', 'I/S Feedback Lin.', 'PID', 'Manual PID', 'Manual PI', 'Manual P'};
            app.ControllerTypeDropDown.Value = 'I/O Feedback Lin.';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = KyvGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end